classdef heyohletsgo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SonohiLTESimulatorUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationSetupTab              matlab.ui.container.Tab
        ResetthefolderstructureandreloadeverythingSwitchLabel  matlab.ui.control.Label
        ResetthefolderstructureandreloadeverythingSwitch  matlab.ui.control.Switch
        CleantheresultsfolderSwitchLabel  matlab.ui.control.Label
        CleantheresultsfolderSwitch     matlab.ui.control.Switch
        EnabledrawingofplotsnetworkdiagramsetcSwitchLabel  matlab.ui.control.Label
        EnabledrawingofplotsnetworkdiagramsetcSwitch  matlab.ui.control.Switch
        StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel  matlab.ui.control.Label
        StorethetransmissiondatathatiseachTBcodewordwaveformSwitch  matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        NumberofschedulingroundtosimulateineachsimulationEditFieldLabel  matlab.ui.control.Label
        NumberofschedulingroundtosimulateineachsimulationEditField  matlab.ui.control.NumericEditField
        SimulationseedEditFieldLabel    matlab.ui.control.Label
        SimulationseedEditField         matlab.ui.control.NumericEditField
        SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel  matlab.ui.control.Label
        SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch  matlab.ui.control.Switch
        GenerateaheatmapforthenetworkscenarioSwitchLabel  matlab.ui.control.Label
        GenerateaheatmapforthenetworkscenarioSwitch  matlab.ui.control.Switch
        TypeofheatmapDropDownLabel      matlab.ui.control.Label
        TypeofheatmapDropDown           matlab.ui.control.DropDown
        HeatmapresolutioninmetresEditFieldLabel  matlab.ui.control.Label
        HeatmapresolutioninmetresEditField  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        NetworkSetupTab                 matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        NumberofResourceBlocksforamacroeNodeBEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforamacroeNodeBEditField  matlab.ui.control.NumericEditField
        NumberofResourceBlocksforamicroeNodeBEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforamicroeNodeBEditField  matlab.ui.control.NumericEditField
        NumberofResourceBlocksforUEsEditFieldLabel  matlab.ui.control.Label
        NumberofResourceBlocksforUEsEditField  matlab.ui.control.NumericEditField
        NumberofMacroeNodeBsEditFieldLabel  matlab.ui.control.Label
        NumberofMacroeNodeBsEditField   matlab.ui.control.NumericEditField
        NumberofMicroeNodeBsEditFieldLabel  matlab.ui.control.Label
        NumberofMicroeNodeBsEditField   matlab.ui.control.NumericEditField
        NumberofUEsEditFieldLabel       matlab.ui.control.Label
        NumberofUEsEditField            matlab.ui.control.NumericEditField
        HeightofMacroeNodeBsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofMacroeNodeBsinmetresEditField  matlab.ui.control.NumericEditField
        HeightofMicroeNodeBsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofMicroeNodeBsinmetresEditField  matlab.ui.control.NumericEditField
        HeightofUEsinmetresEditFieldLabel  matlab.ui.control.Label
        HeightofUEsinmetresEditField    matlab.ui.control.NumericEditField
        PositioningmethodforMicroeNodeBsDropDownLabel  matlab.ui.control.Label
        PositioningmethodforMicroeNodeBsDropDown  matlab.ui.control.DropDown
        RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel  matlab.ui.control.Label
        HeightofUEsinmetresEditField_2  matlab.ui.control.NumericEditField
        MobilityscenariofortheUEsinthenetworkDropDownLabel  matlab.ui.control.Label
        MobilityscenariofortheUEsinthenetworkDropDown  matlab.ui.control.DropDown
        ApplicationtrafficmodelDropDownLabel  matlab.ui.control.Label
        ApplicationtrafficmodelDropDown  matlab.ui.control.DropDown
        PositioningmethodforMicroeNodeBsDropDown_2Label  matlab.ui.control.Label
        PositioningmethodforMicroeNodeBsDropDown_2  matlab.ui.control.DropDown
        PhysicallayersetupTab           matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        UplinkCarrierFrequencyinMHzEditFieldLabel  matlab.ui.control.Label
        UplinkCarrierFrequencyinMHzEditField  matlab.ui.control.NumericEditField
        DownlinkCarrierFrequencyinMHzEditFieldLabel  matlab.ui.control.Label
        DownlinkCarrierFrequencyinMHzEditField  matlab.ui.control.NumericEditField
        NumberofOFDMSymbolsinaRBEditFieldLabel  matlab.ui.control.Label
        NumberofOFDMSymbolsinaRBEditField  matlab.ui.control.NumericEditField
        UENoiseFigureindBEditFieldLabel  matlab.ui.control.Label
        UENoiseFigureindBEditField      matlab.ui.control.NumericEditField
        eNodeBNoiseFigureindBEditFieldLabel  matlab.ui.control.Label
        eNodeBNoiseFigureindBEditField  matlab.ui.control.NumericEditField
        NumberofREinaRBEditFieldLabel   matlab.ui.control.Label
        NumberofREinaRBEditField        matlab.ui.control.NumericEditField
        PRACHIntervalasnumberofsubframesEditFieldLabel  matlab.ui.control.Label
        PRACHIntervalasnumberofsubframesEditField  matlab.ui.control.NumericEditField
        PUCCHformatLabel                matlab.ui.control.Label
        PUCCHformat                     matlab.ui.control.NumericEditField
        HandovertimerinsecondsLabel     matlab.ui.control.Label
        HandoverTimer                   matlab.ui.control.NumericEditField
        SchedulerTab                    matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        PositioningmethodforMicroeNodeBsDropDown_3Label  matlab.ui.control.Label
        SchedulingAlgorithm             matlab.ui.control.DropDown
        UplinkCarrierFrequencyinMHzEditField_2Label  matlab.ui.control.Label
        RefreshAssociationTimer         matlab.ui.control.NumericEditField
        InterferencecoordinationschemeLabel  matlab.ui.control.Label
        InterferenceCoordinationScheme  matlab.ui.control.DropDown
        ChannelTab                      matlab.ui.container.Tab
        DownlinkchannelmodelLabel       matlab.ui.control.Label
        DownlinkChannelModel            matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        UplinkchannelmodelLabel         matlab.ui.control.Label
        UplinkChannelModel              matlab.ui.control.DropDown
        DownlinkchannelmodelLabel_2     matlab.ui.control.Label
        ChannelRegion                   matlab.ui.control.DropDown
        SONTab                          matlab.ui.container.Tab
        UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel  matlab.ui.control.Label
        LowUtilThr                      matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        UtilisationthresholdforswitchingoffeNodeBsEditField_2Label  matlab.ui.control.Label
        HighUtilThr                     matlab.ui.control.NumericEditField
        RadiuswithinwhichaneNodeBlooksforneighboursinmetresLabel  matlab.ui.control.Label
        NboRadius                       matlab.ui.control.NumericEditField
        TimertocontroltheHysteresiscycleinsecondsLabel  matlab.ui.control.Label
        HystTimer                       matlab.ui.control.NumericEditField
        TimertocontroltheswitchingonandoffperiodinsecondsLabel  matlab.ui.control.Label
        SwitchTimer                     matlab.ui.control.NumericEditField
        RunTab                          matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        StartButton                     matlab.ui.control.StateButton
        CancelButton                    matlab.ui.control.StateButton
        LiveTab                         matlab.ui.container.Tab
        ResultsTab                      matlab.ui.container.Tab
    end


    properties (Access = private)
        SimParam % Parameters structure
        SimTraffic % Traffic source
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sonohi(1);
            initParam;
            load 'SimulationParameters' 'Param';
            app.SimParam = Param;
            switchMap = {'Off', 'On'};
            % Simulation
            app.ResetthefolderstructureandreloadeverythingSwitch.Tag = 'reset';
            app.ResetthefolderstructureandreloadeverythingSwitch.Value = switchMap{Param.reset + 1};
            app.CleantheresultsfolderSwitch.Tag = 'rmResults';
            app.CleantheresultsfolderSwitch.Value = switchMap{Param.rmResults + 1};
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.Tag = 'draw';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.Value = switchMap{Param.draw + 1};
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.Tag = 'storeTxData';
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.Value = switchMap{Param.storeTxData + 1};
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Tag = 'schRounds';
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Value = Param.schRounds;
            app.SimulationseedEditField.Tag = 'seed';
            app.SimulationseedEditField.Value = Param.seed;
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Tag = 'saveFrame';
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Value = switchMap{Param.saveFrame + 1};
            app.GenerateaheatmapforthenetworkscenarioSwitch.Tag = 'generateHeatMap';
            app.GenerateaheatmapforthenetworkscenarioSwitch.Value = switchMap{Param.generateHeatMap + 1};
            app.TypeofheatmapDropDown.Tag = 'heatMapType';
            app.TypeofheatmapDropDown.Value = Param.heatMapType;
            app.HeatmapresolutioninmetresEditField.Tag = 'heatMapRes';
            app.HeatmapresolutioninmetresEditField.Value = Param.heatMapRes;            
            
            % Network
            app.NumberofResourceBlocksforamacroeNodeBEditField.Tag = 'numSubFramesMacro';
            app.NumberofResourceBlocksforamacroeNodeBEditField.Value = Param.numSubFramesMacro;
            app.NumberofResourceBlocksforamicroeNodeBEditField.Tag = 'numSubFramesMicro';
            app.NumberofResourceBlocksforamicroeNodeBEditField.Value = Param.numSubFramesMicro;
            app.NumberofResourceBlocksforUEsEditField.Tag = 'numSubFramesUE';
            app.NumberofResourceBlocksforUEsEditField.Value = Param.numSubFramesUE;
            app.NumberofMacroeNodeBsEditField.Tag = 'numMacro';
            app.NumberofMacroeNodeBsEditField.Value = Param.numMacro;
            app.NumberofMicroeNodeBsEditField.Tag = 'numMicro';
            app.NumberofMicroeNodeBsEditField.Value = Param.numMicro;
            app.NumberofUEsEditField.Tag = 'numUsers';
            app.NumberofUEsEditField.Value = Param.numUsers;
            app.HeightofMacroeNodeBsinmetresEditField.Tag = 'macroHeight';
            app.HeightofMacroeNodeBsinmetresEditField.Value = Param.macroHeight;
            app.HeightofMicroeNodeBsinmetresEditField.Tag = 'microHeight';
            app.HeightofMicroeNodeBsinmetresEditField.Value = Param.microHeight;
            app.HeightofUEsinmetresEditField.Tag = 'ueHeight';
            app.HeightofUEsinmetresEditField.Value = Param.ueHeight;
            app.PositioningmethodforMicroeNodeBsDropDown.Tag = 'microPos';
            app.PositioningmethodforMicroeNodeBsDropDown.Value = Param.microPos;
            app.HeightofUEsinmetresEditField_2.Tag = 'microUniformRadius';
            app.HeightofUEsinmetresEditField_2.Value = Param.microUniformRadius;
            app.MobilityscenariofortheUEsinthenetworkDropDown.Tag = 'mobilityScenario';
            app.MobilityscenariofortheUEsinthenetworkDropDown.Value = Param.mobilityScenario;
            app.ApplicationtrafficmodelDropDown.Tag = 'trafficModel';
            app.ApplicationtrafficmodelDropDown.Value = Param.trafficModel;
            
            % Physical layer
            app.UplinkCarrierFrequencyinMHzEditField.Tag = 'ulFreq';
            app.UplinkCarrierFrequencyinMHzEditField.Value = Param.ulFreq;
            app.DownlinkCarrierFrequencyinMHzEditField.Tag = 'dlFreq';
            app.DownlinkCarrierFrequencyinMHzEditField.Value = Param.dlFreq;
            app.NumberofOFDMSymbolsinaRBEditField.Tag = 'prbSym';
            app.NumberofOFDMSymbolsinaRBEditField.Value = Param.prbSym;
            app.UENoiseFigureindBEditField.Tag = 'ueNoiseFigure';
            app.UENoiseFigureindBEditField.Value = Param.ueNoiseFigure;
            app.eNodeBNoiseFigureindBEditField.Tag = 'bsNoiseFigure';
            app.eNodeBNoiseFigureindBEditField.Value = Param.bsNoiseFigure;
            app.NumberofREinaRBEditField.Tag = 'prbRe';
            app.NumberofREinaRBEditField.Value = Param.prbRe;
            app.PRACHIntervalasnumberofsubframesEditField.Tag = 'PRACHInterval';
            app.PRACHIntervalasnumberofsubframesEditField.Value = Param.PRACHInterval;
            app.PUCCHformat.Tag = 'pucchFormat';
            app.PUCCHformat.Value = Param.pucchFormat;
            app.HandoverTimer.Tag = 'handoverTimer';
            app.HandoverTimer.Value = Param.handoverTimer;
            
            % Scheduler
            app.SchedulingAlgorithm.Tag = 'scheduling';
            app.SchedulingAlgorithm.Value = Param.scheduling;
            app.RefreshAssociationTimer.Tag = 'refreshAssociationTimer';
            app.RefreshAssociationTimer.Value = Param.refreshAssociationTimer;
            app.InterferenceCoordinationScheme.Tag = 'icScheme';
            app.InterferenceCoordinationScheme.Value = Param.icScheme;
            
            % Channel
            app.DownlinkChannelModel.Tag = 'modeDL';
            app.DownlinkChannelModel.Value = Param.channel.modeDL;
            app.UplinkChannelModel.Tag = 'modeUL';
            app.UplinkChannelModel.Value = Param.channel.modeUL;
            app.ChannelRegion.Tag = 'DenseUrban';
            app.ChannelRegion.Value = Param.channel.region;
            
            % SON
            app.LowUtilThr.Tag = 'utilLoThr';
            app.LowUtilThr.Value = Param.utilLoThr;
            app.HighUtilThr.Tag = 'utilHiThr';
            app.HighUtilThr.Value = Param.utilHiThr;
            app.NboRadius.Tag = 'nboRadius';
            app.NboRadius.Value = Param.nboRadius;
            app.HystTimer.Tag = 'tHyst';
            app.HystTimer.Value = Param.tHyst;
            app.SwitchTimer.Tag = 'tSwitch';
            app.SwitchTimer.Value = Param.tSwitch;


        end

        % Value changed function: ApplicationtrafficmodelDropDown, 
        % DownlinkCarrierFrequencyinMHzEditField, HandoverTimer, 
        % HeatmapresolutioninmetresEditField, 
        % HeightofMacroeNodeBsinmetresEditField, 
        % HeightofMicroeNodeBsinmetresEditField, 
        % HeightofUEsinmetresEditField, HeightofUEsinmetresEditField_2, 
        % HighUtilThr, HystTimer, LowUtilThr, 
        % MobilityscenariofortheUEsinthenetworkDropDown, NboRadius, 
        % NumberofMacroeNodeBsEditField, NumberofMicroeNodeBsEditField, 
        % NumberofOFDMSymbolsinaRBEditField, 
        % NumberofResourceBlocksforUEsEditField, 
        % NumberofResourceBlocksforamacroeNodeBEditField, 
        % NumberofResourceBlocksforamicroeNodeBEditField, 
        % NumberofUEsEditField, 
        % NumberofschedulingroundtosimulateineachsimulationEditField, 
        % PUCCHformat, PositioningmethodforMicroeNodeBsDropDown, 
        % RefreshAssociationTimer, SchedulingAlgorithm, 
        % SimulationseedEditField, SwitchTimer, TypeofheatmapDropDown, 
        % UENoiseFigureindBEditField, 
        % UplinkCarrierFrequencyinMHzEditField, 
        % eNodeBNoiseFigureindBEditField
        function setParamValue(app, event)
            app.SimParam.(event.Source.Tag) = event.Value;
        end

        % Value changed function: CleantheresultsfolderSwitch, 
        % EnabledrawingofplotsnetworkdiagramsetcSwitch, 
        % GenerateaheatmapforthenetworkscenarioSwitch, 
        % ResetthefolderstructureandreloadeverythingSwitch, 
        % SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch, 
        % StorethetransmissiondatathatiseachTBcodewordwaveformSwitch
        function setParamBooleanValue(app, event)
            value = 0;
            if event.Value == 'On'
                value = 1;
            end
            app.SimParam.(event.Source.Tag) = value;
        end

        % Value changed function: StartButton
        function saveParamAndRun(app, event)
           app.SimParam.buildings = load('mobility/buildings.txt');
            app.SimParam.buildingHeight = [20,50];
            app.SimParam.area = [min(app.SimParam.buildings(:, 1)), min(app.SimParam.buildings(:, 2)), ...
            	max(app.SimParam.buildings(:, 3)), max(app.SimParam.buildings(:, 4))];
            app.SimParam.buildings(:,5) = randi([app.SimParam.buildingHeight],[1 length(app.SimParam.buildings(:,1))]);
            
            app.SimParam.harq.tout = app.SimParam.harq.proc/2 -1;
            
            % Get traffic source data and check if we have already the MAT file with the traffic data
            switch app.SimParam.trafficModel
            	case 'videoStreaming'
            	   if (exist('traffic/videoStreaming.mat', 'file') ~= 2 || app.SimParam.reset)
            	       app.SimTraffic = loadVideoStreamingTraffic('traffic/videoStreaming.csv', true);
            	   else
            	       load 'traffic/videoStreaming.mat' 'trSource';
                       app.SimTraffic = trSource;
            	   end
            	case 'fullBuffer'
            	   if (exist('traffic/fullBuffer.mat', 'file') ~= 2 || app.SimParam.reset)
            	       app.SimTraffic = loadFullBufferTraffic('traffic/fullBuffer.csv');
            	   else
            	       load 'traffic/fullBuffer.mat' 'trSource';
                       app.SimTraffic = trSource;
            	   end
            end
            Param = app.SimParam;
            trSource = app.SimTraffic;
            save(strcat('SimulationParameters.mat'), 'Param', 'trSource');
            
            % RUN!!!!
            app.StartButton.Enable = 'off';
            app.CancelButton.Enable = 'on';
            main;
        end

        % Value changed function: ChannelRegion, 
        % DownlinkChannelModel, UplinkChannelModel
        function setChannelParam(app, event)
            app.SimParam.channel.(event.Source.Tag) = event.Value;      
        end

        % Value changed function: CancelButton
        function abortSimulation(app, event)
            app.StartButton.Enable = 'on';
            app.CancelButton.Enable = 'off';
            quit();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SonohiLTESimulatorUIFigure = uifigure;
            app.SonohiLTESimulatorUIFigure.Name = 'Sonohi LTE Simulator';
            app.TabGroup = uitabgroup(app.SonohiLTESimulatorUIFigure);
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.ResetthefolderstructureandreloadeverythingSwitchLabel = uilabel(app.SimulationSetupTab);
            app.ResetthefolderstructureandreloadeverythingSwitchLabel.Text = 'Reset the folder structure and reload everything';
            app.ResetthefolderstructureandreloadeverythingSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.ResetthefolderstructureandreloadeverythingSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.CleantheresultsfolderSwitchLabel = uilabel(app.SimulationSetupTab);
            app.CleantheresultsfolderSwitchLabel.Text = 'Clean the results folder';
            app.CleantheresultsfolderSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.CleantheresultsfolderSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.CleantheresultsfolderSwitch.Value = 'On';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitchLabel = uilabel(app.SimulationSetupTab);
            app.EnabledrawingofplotsnetworkdiagramsetcSwitchLabel.Text = 'Enable drawing of plots, network diagrams, etc. ';
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.EnabledrawingofplotsnetworkdiagramsetcSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel = uilabel(app.SimulationSetupTab);
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitchLabel.Text = 'Store the transmission data, that is each TB, codeword, waveform';
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.StorethetransmissiondatathatiseachTBcodewordwaveformSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.Label = uilabel(app.SimulationSetupTab);
            app.Label.Text = 'The parameters in this tab can be used to setup overall simulation parameters';
            app.NumberofschedulingroundtosimulateineachsimulationEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.NumberofschedulingroundtosimulateineachsimulationEditFieldLabel.Text = 'Number of scheduling round to simulate in each simulation';
            app.NumberofschedulingroundtosimulateineachsimulationEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Limits = [1 20000];
            app.NumberofschedulingroundtosimulateineachsimulationEditField.ValueDisplayFormat = '%.0f';
            app.NumberofschedulingroundtosimulateineachsimulationEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofschedulingroundtosimulateineachsimulationEditField.Value = 5;
            app.SimulationseedEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.SimulationseedEditFieldLabel.Text = 'Simulation seed';
            app.SimulationseedEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.SimulationseedEditField.Limits = [1 Inf];
            app.SimulationseedEditField.ValueDisplayFormat = '%.0f';
            app.SimulationseedEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.SimulationseedEditField.Value = 42;
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel = uilabel(app.SimulationSetupTab);
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitchLabel.Text = 'Save a whole LTE frame for the macro eNodeB (primarily for debug)';
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.SaveawholeLTEframeforthemacroeNodeBprimarilyfordebugSwitch.Value = 'On';
            app.GenerateaheatmapforthenetworkscenarioSwitchLabel = uilabel(app.SimulationSetupTab);
            app.GenerateaheatmapforthenetworkscenarioSwitchLabel.Text = 'Generate a heatmap for the network scenario';
            app.GenerateaheatmapforthenetworkscenarioSwitch = uiswitch(app.SimulationSetupTab, 'slider');
            app.GenerateaheatmapforthenetworkscenarioSwitch.ValueChangedFcn = createCallbackFcn(app, @setParamBooleanValue, true);
            app.GenerateaheatmapforthenetworkscenarioSwitch.Value = 'On';
            app.TypeofheatmapDropDownLabel = uilabel(app.SimulationSetupTab);
            app.TypeofheatmapDropDownLabel.Text = 'Type of heatmap';
            app.TypeofheatmapDropDown = uidropdown(app.SimulationSetupTab);
            app.TypeofheatmapDropDown.Items = {'perStation', 'perClass'};
            app.TypeofheatmapDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.TypeofheatmapDropDown.Value = 'perStation';
            app.HeatmapresolutioninmetresEditFieldLabel = uilabel(app.SimulationSetupTab);
            app.HeatmapresolutioninmetresEditFieldLabel.Text = 'Heatmap resolution in metres';
            app.HeatmapresolutioninmetresEditField = uieditfield(app.SimulationSetupTab, 'numeric');
            app.HeatmapresolutioninmetresEditField.Limits = [5 100];
            app.HeatmapresolutioninmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeatmapresolutioninmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeatmapresolutioninmetresEditField.Value = 10;
            app.Label_4 = uilabel(app.SimulationSetupTab);
            app.Label_4.Text = 'Heatmap parameters';
            app.NetworkSetupTab = uitab(app.TabGroup);
            app.NetworkSetupTab.Title = 'Network Setup';
            app.Label_2 = uilabel(app.NetworkSetupTab);
            app.Label_2.Text = 'The parameters in this tab can be used to setup the network';
            app.NumberofResourceBlocksforamacroeNodeBEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforamacroeNodeBEditFieldLabel.Text = 'Number of Resource Blocks for a macro eNodeB';
            app.NumberofResourceBlocksforamacroeNodeBEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforamacroeNodeBEditField.Limits = [1 100];
            app.NumberofResourceBlocksforamacroeNodeBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforamacroeNodeBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforamacroeNodeBEditField.Value = 50;
            app.NumberofResourceBlocksforamicroeNodeBEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforamicroeNodeBEditFieldLabel.Text = 'Number of Resource Blocks for a micro eNodeB';
            app.NumberofResourceBlocksforamicroeNodeBEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforamicroeNodeBEditField.Limits = [6 100];
            app.NumberofResourceBlocksforamicroeNodeBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforamicroeNodeBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforamicroeNodeBEditField.Value = 25;
            app.NumberofResourceBlocksforUEsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofResourceBlocksforUEsEditFieldLabel.Text = 'Number of Resource Blocks for UEs';
            app.NumberofResourceBlocksforUEsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofResourceBlocksforUEsEditField.Limits = [6 100];
            app.NumberofResourceBlocksforUEsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofResourceBlocksforUEsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofResourceBlocksforUEsEditField.Value = 25;
            app.NumberofMacroeNodeBsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofMacroeNodeBsEditFieldLabel.Text = 'Number of Macro eNodeBs';
            app.NumberofMacroeNodeBsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofMacroeNodeBsEditField.Limits = [1 2];
            app.NumberofMacroeNodeBsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMacroeNodeBsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofMacroeNodeBsEditField.Editable = 'off';
            app.NumberofMacroeNodeBsEditField.Value = 1;
            app.NumberofMicroeNodeBsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofMicroeNodeBsEditFieldLabel.Text = 'Number of Micro eNodeBs';
            app.NumberofMicroeNodeBsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofMicroeNodeBsEditField.Limits = [1 10];
            app.NumberofMicroeNodeBsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMicroeNodeBsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofMicroeNodeBsEditField.Value = 2;
            app.NumberofUEsEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.NumberofUEsEditFieldLabel.Text = 'Number of UEs';
            app.NumberofUEsEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.NumberofUEsEditField.Limits = [1 50];
            app.NumberofUEsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofUEsEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofUEsEditField.Value = 2;
            app.HeightofMacroeNodeBsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofMacroeNodeBsinmetresEditFieldLabel.Text = 'Height of Macro eNodeBs in metres';
            app.HeightofMacroeNodeBsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofMacroeNodeBsinmetresEditField.Limits = [1 100];
            app.HeightofMacroeNodeBsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofMacroeNodeBsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofMacroeNodeBsinmetresEditField.Value = 35;
            app.HeightofMicroeNodeBsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofMicroeNodeBsinmetresEditFieldLabel.Text = 'Height of Micro eNodeBs in metres';
            app.HeightofMicroeNodeBsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofMicroeNodeBsinmetresEditField.Limits = [1 100];
            app.HeightofMicroeNodeBsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofMicroeNodeBsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofMicroeNodeBsinmetresEditField.Value = 25;
            app.HeightofUEsinmetresEditFieldLabel = uilabel(app.NetworkSetupTab);
            app.HeightofUEsinmetresEditFieldLabel.Text = 'Height of UEs in metres';
            app.HeightofUEsinmetresEditField = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofUEsinmetresEditField.Limits = [0 3];
            app.HeightofUEsinmetresEditField.ValueDisplayFormat = '%.2f';
            app.HeightofUEsinmetresEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofUEsinmetresEditField.Value = 1.5;
            app.PositioningmethodforMicroeNodeBsDropDownLabel = uilabel(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDownLabel.Text = 'Positioning method for Micro eNodeBs';
            app.PositioningmethodforMicroeNodeBsDropDown = uidropdown(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDown.Items = {'uniform', 'random'};
            app.PositioningmethodforMicroeNodeBsDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.PositioningmethodforMicroeNodeBsDropDown.Value = 'uniform';
            app.RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel = uilabel(app.NetworkSetupTab);
            app.RadiusfromcentreofnetworkforuniformMicroeNodeBspositioningLabel.Text = 'Radius in metres from centre of network for uniform Micro eNodeBs positioning';
            app.HeightofUEsinmetresEditField_2 = uieditfield(app.NetworkSetupTab, 'numeric');
            app.HeightofUEsinmetresEditField_2.Limits = [10 200];
            app.HeightofUEsinmetresEditField_2.ValueDisplayFormat = '%.2f';
            app.HeightofUEsinmetresEditField_2.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HeightofUEsinmetresEditField_2.Value = 100;
            app.MobilityscenariofortheUEsinthenetworkDropDownLabel = uilabel(app.NetworkSetupTab);
            app.MobilityscenariofortheUEsinthenetworkDropDownLabel.Text = 'Mobility scenario for the UEs in the network';
            app.MobilityscenariofortheUEsinthenetworkDropDown = uidropdown(app.NetworkSetupTab);
            app.MobilityscenariofortheUEsinthenetworkDropDown.Items = {'static', 'pedestrian', 'vehicular', 'superman'};
            app.MobilityscenariofortheUEsinthenetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.MobilityscenariofortheUEsinthenetworkDropDown.Value = 'static';
            app.ApplicationtrafficmodelDropDownLabel = uilabel(app.NetworkSetupTab);
            app.ApplicationtrafficmodelDropDownLabel.Text = 'Application traffic model';
            app.ApplicationtrafficmodelDropDown = uidropdown(app.NetworkSetupTab);
            app.ApplicationtrafficmodelDropDown.Items = {'fullBuffer', 'videoStreaming'};
            app.ApplicationtrafficmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.ApplicationtrafficmodelDropDown.Value = 'fullBuffer';
            app.PositioningmethodforMicroeNodeBsDropDown_2Label = uilabel(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDown_2Label.Text = 'Positioning method for Micro eNodeBs';
            app.PositioningmethodforMicroeNodeBsDropDown_2 = uidropdown(app.NetworkSetupTab);
            app.PositioningmethodforMicroeNodeBsDropDown_2.Items = {'uniform', 'random'};
            app.PositioningmethodforMicroeNodeBsDropDown_2.Value = 'uniform';
            app.PhysicallayersetupTab = uitab(app.TabGroup);
            app.PhysicallayersetupTab.Title = 'Physical layer setup';
            app.Label_5 = uilabel(app.PhysicallayersetupTab);
            app.Label_5.Text = 'The parameters in this tab can be used to setup the physical layer';
            app.UplinkCarrierFrequencyinMHzEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.UplinkCarrierFrequencyinMHzEditFieldLabel.Text = 'Uplink Carrier Frequency in MHz';
            app.UplinkCarrierFrequencyinMHzEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.UplinkCarrierFrequencyinMHzEditField.Limits = [800 3600];
            app.UplinkCarrierFrequencyinMHzEditField.ValueDisplayFormat = '%.2f';
            app.UplinkCarrierFrequencyinMHzEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.UplinkCarrierFrequencyinMHzEditField.Value = 1747.5;
            app.DownlinkCarrierFrequencyinMHzEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.DownlinkCarrierFrequencyinMHzEditFieldLabel.Text = 'Downlink Carrier Frequency in MHz';
            app.DownlinkCarrierFrequencyinMHzEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.DownlinkCarrierFrequencyinMHzEditField.Limits = [800 3600];
            app.DownlinkCarrierFrequencyinMHzEditField.ValueDisplayFormat = '%.2f';
            app.DownlinkCarrierFrequencyinMHzEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.DownlinkCarrierFrequencyinMHzEditField.Value = 1842.5;
            app.NumberofOFDMSymbolsinaRBEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.NumberofOFDMSymbolsinaRBEditFieldLabel.Text = 'Number of OFDM Symbols in a RB';
            app.NumberofOFDMSymbolsinaRBEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.NumberofOFDMSymbolsinaRBEditField.Limits = [1 160];
            app.NumberofOFDMSymbolsinaRBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofOFDMSymbolsinaRBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NumberofOFDMSymbolsinaRBEditField.Value = 160;
            app.UENoiseFigureindBEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.UENoiseFigureindBEditFieldLabel.Text = 'UE Noise Figure in dB';
            app.UENoiseFigureindBEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.UENoiseFigureindBEditField.ValueDisplayFormat = '%.2f';
            app.UENoiseFigureindBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.UENoiseFigureindBEditField.Value = 7;
            app.eNodeBNoiseFigureindBEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.eNodeBNoiseFigureindBEditFieldLabel.Text = 'eNodeB Noise Figure in dB';
            app.eNodeBNoiseFigureindBEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.eNodeBNoiseFigureindBEditField.ValueDisplayFormat = '%.2f';
            app.eNodeBNoiseFigureindBEditField.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.eNodeBNoiseFigureindBEditField.Value = 3;
            app.NumberofREinaRBEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.NumberofREinaRBEditFieldLabel.Text = 'Number of RE in a RB';
            app.NumberofREinaRBEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.NumberofREinaRBEditField.Limits = [1 168];
            app.NumberofREinaRBEditField.ValueDisplayFormat = '%.0f';
            app.NumberofREinaRBEditField.Value = 168;
            app.PRACHIntervalasnumberofsubframesEditFieldLabel = uilabel(app.PhysicallayersetupTab);
            app.PRACHIntervalasnumberofsubframesEditFieldLabel.Text = 'PRACH Interval as number of subframes';
            app.PRACHIntervalasnumberofsubframesEditField = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.PRACHIntervalasnumberofsubframesEditField.Limits = [1 10];
            app.PRACHIntervalasnumberofsubframesEditField.ValueDisplayFormat = '%.0f';
            app.PRACHIntervalasnumberofsubframesEditField.Value = 10;
            app.PUCCHformatLabel = uilabel(app.PhysicallayersetupTab);
            app.PUCCHformatLabel.Text = 'PUCCH format';
            app.PUCCHformat = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.PUCCHformat.Limits = [2 3];
            app.PUCCHformat.ValueDisplayFormat = '%.0f';
            app.PUCCHformat.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.PUCCHformat.Value = 2;
            app.HandovertimerinsecondsLabel = uilabel(app.PhysicallayersetupTab);
            app.HandovertimerinsecondsLabel.Text = 'Handover timer in seconds (time needed from starting a handover to its completion)';
            app.HandoverTimer = uieditfield(app.PhysicallayersetupTab, 'numeric');
            app.HandoverTimer.Limits = [0.001 10];
            app.HandoverTimer.ValueDisplayFormat = '%.2f';
            app.HandoverTimer.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HandoverTimer.Value = 0.01;
            app.SchedulerTab = uitab(app.TabGroup);
            app.SchedulerTab.Title = 'Scheduler';
            app.Label_7 = uilabel(app.SchedulerTab);
            app.Label_7.Text = 'The parameters in this tab can be used to setup available scheduling attributes';
            app.PositioningmethodforMicroeNodeBsDropDown_3Label = uilabel(app.SchedulerTab);
            app.PositioningmethodforMicroeNodeBsDropDown_3Label.Text = 'Scheduling policy (round robin is slightly more greedy than the default version)';
            app.SchedulingAlgorithm = uidropdown(app.SchedulerTab);
            app.SchedulingAlgorithm.Items = {'roundRobin', 'random'};
            app.SchedulingAlgorithm.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.SchedulingAlgorithm.Value = 'roundRobin';
            app.UplinkCarrierFrequencyinMHzEditField_2Label = uilabel(app.SchedulerTab);
            app.UplinkCarrierFrequencyinMHzEditField_2Label.Text = 'Interval to run the routine to refresh the UE association with the eNodeBs in seconds';
            app.RefreshAssociationTimer = uieditfield(app.SchedulerTab, 'numeric');
            app.RefreshAssociationTimer.Limits = [0.001 1];
            app.RefreshAssociationTimer.ValueDisplayFormat = '%.4f';
            app.RefreshAssociationTimer.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.RefreshAssociationTimer.Value = 0.002;
            app.InterferencecoordinationschemeLabel = uilabel(app.SchedulerTab);
            app.InterferencecoordinationschemeLabel.Text = 'Interference coordination scheme';
            app.InterferenceCoordinationScheme = uidropdown(app.SchedulerTab);
            app.InterferenceCoordinationScheme.Items = {'none'};
            app.InterferenceCoordinationScheme.Value = 'none';
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.DownlinkchannelmodelLabel = uilabel(app.ChannelTab);
            app.DownlinkchannelmodelLabel.Text = 'Downlink channel model';
            app.DownlinkChannelModel = uidropdown(app.ChannelTab);
            app.DownlinkChannelModel.Items = {'winner', 'eHATA', 'B2B'};
            app.DownlinkChannelModel.ValueChangedFcn = createCallbackFcn(app, @setChannelParam, true);
            app.DownlinkChannelModel.Value = 'winner';
            app.Label_8 = uilabel(app.ChannelTab);
            app.Label_8.Text = 'The parameters in this tab can be used to setup the channel models';
            app.UplinkchannelmodelLabel = uilabel(app.ChannelTab);
            app.UplinkchannelmodelLabel.Text = 'Uplink channel model';
            app.UplinkChannelModel = uidropdown(app.ChannelTab);
            app.UplinkChannelModel.Items = {'winner', 'eHATA', 'B2B'};
            app.UplinkChannelModel.ValueChangedFcn = createCallbackFcn(app, @setChannelParam, true);
            app.UplinkChannelModel.Value = 'B2B';
            app.DownlinkchannelmodelLabel_2 = uilabel(app.ChannelTab);
            app.DownlinkchannelmodelLabel_2.Text = 'Downlink channel model';
            app.ChannelRegion = uidropdown(app.ChannelTab);
            app.ChannelRegion.Items = {'DenseUrban'};
            app.ChannelRegion.ValueChangedFcn = createCallbackFcn(app, @setChannelParam, true);
            app.ChannelRegion.Value = 'DenseUrban';
            app.SONTab = uitab(app.TabGroup);
            app.SONTab.Title = 'SON';
            app.UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel = uilabel(app.SONTab);
            app.UtilisationthresholdforswitchingoffeNodeBsEditFieldLabel.Text = 'Utilisation threshold for switching off eNodeBs ';
            app.LowUtilThr = uieditfield(app.SONTab, 'numeric');
            app.LowUtilThr.Limits = [1 50];
            app.LowUtilThr.ValueDisplayFormat = '%.0f';
            app.LowUtilThr.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.LowUtilThr.Editable = 'off';
            app.LowUtilThr.Value = 1;
            app.Label_3 = uilabel(app.SONTab);
            app.Label_3.Text = 'The parameters in this tab can be used to configure power saving and SON features';
            app.UtilisationthresholdforswitchingoffeNodeBsEditField_2Label = uilabel(app.SONTab);
            app.UtilisationthresholdforswitchingoffeNodeBsEditField_2Label.Text = 'Utilisation threshold for starting offloading procedures to other eNodeBs ';
            app.HighUtilThr = uieditfield(app.SONTab, 'numeric');
            app.HighUtilThr.Limits = [50 100];
            app.HighUtilThr.ValueDisplayFormat = '%.0f';
            app.HighUtilThr.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HighUtilThr.Editable = 'off';
            app.HighUtilThr.Value = 100;
            app.RadiuswithinwhichaneNodeBlooksforneighboursinmetresLabel = uilabel(app.SONTab);
            app.RadiuswithinwhichaneNodeBlooksforneighboursinmetresLabel.Text = 'Radius within which an eNodeB looks for neighbours in metres';
            app.NboRadius = uieditfield(app.SONTab, 'numeric');
            app.NboRadius.Limits = [0.5 200];
            app.NboRadius.ValueDisplayFormat = '%.2f';
            app.NboRadius.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.NboRadius.Editable = 'off';
            app.NboRadius.Value = 100;
            app.TimertocontroltheHysteresiscycleinsecondsLabel = uilabel(app.SONTab);
            app.TimertocontroltheHysteresiscycleinsecondsLabel.Text = 'Timer to control the Hysteresis cycle in seconds';
            app.HystTimer = uieditfield(app.SONTab, 'numeric');
            app.HystTimer.Limits = [0.001 100];
            app.HystTimer.ValueDisplayFormat = '%.4f';
            app.HystTimer.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.HystTimer.Editable = 'off';
            app.HystTimer.Value = 0.01;
            app.TimertocontroltheswitchingonandoffperiodinsecondsLabel = uilabel(app.SONTab);
            app.TimertocontroltheswitchingonandoffperiodinsecondsLabel.Text = 'Timer to control the switching on and off period in seconds';
            app.SwitchTimer = uieditfield(app.SONTab, 'numeric');
            app.SwitchTimer.Limits = [0.001 100];
            app.SwitchTimer.ValueDisplayFormat = '%.4f';
            app.SwitchTimer.ValueChangedFcn = createCallbackFcn(app, @setParamValue, true);
            app.SwitchTimer.Editable = 'off';
            app.SwitchTimer.Value = 0.01;
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.TabGroup2 = uitabgroup(app.RunTab);
            app.StartTab = uitab(app.TabGroup2);
            app.StartTab.Title = 'Start';
            app.StartButton = uibutton(app.StartTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @saveParamAndRun, true);
            app.StartButton.Icon = 'startIcon.png';
            app.StartButton.Text = '';
            app.CancelButton = uibutton(app.StartTab, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @abortSimulation, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Icon = 'cancelIcon.png';
            app.CancelButton.Text = '';
            app.LiveTab = uitab(app.TabGroup2);
            app.LiveTab.Title = 'Live';
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = heyohletsgo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SonohiLTESimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SonohiLTESimulatorUIFigure)
        end
    end
end