classdef monster < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    MONSTeRUIFigure                 matlab.ui.Figure
    TabGroup                        matlab.ui.container.TabGroup
    HomeTab                         matlab.ui.container.Tab
    Image                           matlab.ui.control.Image
    SetupTab                        matlab.ui.container.Tab
    TabGroup2                       matlab.ui.container.TabGroup
    RuntimeTab                      matlab.ui.container.Tab
    Label                           matlab.ui.control.Label
    RuntimesetupLabel               matlab.ui.control.Label
    Simulationrounds1roundis1msEditFieldLabel  matlab.ui.control.Label
    Simulationrounds1roundis1msEditField  matlab.ui.control.NumericEditField
    SimulationseedEditFieldLabel    matlab.ui.control.Label
    SimulationseedEditField         matlab.ui.control.NumericEditField
    NetworkTab                      matlab.ui.container.Tab
    RunTab                          matlab.ui.container.Tab
    Label_2                         matlab.ui.control.Label
    RunasimulationLabel             matlab.ui.control.Label
    Label_3                         matlab.ui.control.Label
    LoadsetupButton_2               matlab.ui.control.Button
    LoadthesetuptorunButton         matlab.ui.control.Button
    SimulationexecutionlogTextAreaLabel  matlab.ui.control.Label
    SimulationexecutionlogTextArea  matlab.ui.control.TextArea
    ResultsTab                      matlab.ui.container.Tab
  end

 
 properties (Access = private)
  simulationRounds % number of simulation rounds
  seed
  Config
  Logger
  Simulation
 end
  
  methods (Access = private)
    
      function app = updateAppLog(app)
          logStore = app.Simulation.Logger.logStore;
          app.SimulationexecutionlogTextArea.Value = logStore.messages;      
    end
  end
 

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      
    end

    % Value changed function: 
    % Simulationrounds1roundis1msEditField
    function Simulationrounds1roundis1msEditFieldValueChanged(app, event)
       value = app.Simulationrounds1roundis1msEditField.Value;
       app.simulationRounds = value;
    end

    % Button pushed function: LoadsetupButton_2
    function LoadsetupButton_2Pushed(app, event)
       set(app.LoadsetupButton_2, 'Text', 'Loading setup...')
       set(app.LoadsetupButton_2, 'Enable', 'off')
       app.Config = MonsterConfig();
       if ~isempty(app.simulationRounds)
           app.Config.Runtime.simulationRounds = app.simulationRounds;
       end
       app.Config.Runtime.mode = 'app';
       % Pause 2 secs
       pause(2);
       set(app.LoadsetupButton_2, 'Text', 'Setup loaded')
       set(app.LoadthesetuptorunButton, 'Enable', 'on')
       set(app.LoadthesetuptorunButton, 'Text', 'Run')
    end

    % Button pushed function: LoadthesetuptorunButton
    function LoadthesetuptorunButtonPushed(app, event)
       set(app.LoadthesetuptorunButton, 'Enable', 'off');
       set(app.LoadthesetuptorunButton, 'Text', 'Simulation running');
       app.LoadthesetuptorunButton
       app.SimulationexecutionlogTextArea.Value = ["Simulation initialising..."];
       app.Logger = MonsterLog(app.Config);
       app.Simulation = Monster(app.Config, app.Logger);
       app.Simulation.Logger.log('(MAIN) simulation instance created, starting...', 'NFO');
       for iRound = 0:(app.Simulation.Runtime.totalRounds - 1)
       	app.Simulation.setupRound(iRound);
        app.updateAppLog();
       	app.Simulation.Logger.log(sprintf('(MAIN) simulation round %i, time elapsed %f s, time left %f s',...
       		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.currentTime, ...
       		app.Simulation.Runtime.remainingTime ), 'NFO');
       	app.updateAppLog();
       	app.Simulation.run();
        app.updateAppLog();
       	app.Simulation.Logger.log(sprintf('(MAIN) completed simulation round %i. %i rounds left' ,....
       		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.remainingRounds), 'NFO');
        app.updateAppLog();
       	app.Simulation.collectResults();
        app.updateAppLog();
       	app.Simulation.Logger.log('(MAIN) collected simulation round results', 'NFO');
        app.updateAppLog();
       	app.Simulation.clean();
        app.updateAppLog();
       	if iRound ~= app.Simulation.Runtime.totalRounds - 1
       	    app.Simulation.Logger.log('(MAIN) cleaned parameters for next round', 'NFO');
       	else
       	    app.Simulation.Logger.log('(MAIN) simulation completed', 'NFO');
       	end
        app.updateAppLog();
       end
       set(app.LoadsetupButton_2, 'Enable', 'on')
       set(app.LoadsetupButton_2, 'Text', 'Load setup')
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.MONSTeRUIFigure = uifigure('Visible', 'off');
      app.MONSTeRUIFigure.Name = 'MONSTeR';
      app.TabGroup = uitabgroup(app.MONSTeRUIFigure);
      app.TabGroup.Tooltip = {''};
      app.HomeTab = uitab(app.TabGroup);
      app.HomeTab.Title = 'Home';
      app.Image = uiimage(app.HomeTab);
      app.Image.ImageSource = 'logo_large.png';
      app.SetupTab = uitab(app.TabGroup);
      app.SetupTab.Title = 'Setup';
      app.TabGroup2 = uitabgroup(app.SetupTab);
      app.RuntimeTab = uitab(app.TabGroup2);
      app.RuntimeTab.Title = 'Runtime';
      app.Label = uilabel(app.RuntimeTab);
      app.Label.Text = 'Use settings in this tab to edit the configuration for the simulation runtime, like length, etc.';
      app.RuntimesetupLabel = uilabel(app.RuntimeTab);
      app.RuntimesetupLabel.Text = 'Runtime setup';
      app.Simulationrounds1roundis1msEditFieldLabel = uilabel(app.RuntimeTab);
      app.Simulationrounds1roundis1msEditFieldLabel.Text = 'Simulation rounds (1 round is 1ms)';
      app.Simulationrounds1roundis1msEditField = uieditfield(app.RuntimeTab, 'numeric');
      app.Simulationrounds1roundis1msEditField.Limits = [1 Inf];
      app.Simulationrounds1roundis1msEditField.RoundFractionalValues = 'on';
      app.Simulationrounds1roundis1msEditField.ValueChangedFcn = createCallbackFcn(app, @Simulationrounds1roundis1msEditFieldValueChanged, true);
      app.Simulationrounds1roundis1msEditField.Value = 10;
      app.SimulationseedEditFieldLabel = uilabel(app.RuntimeTab);
      app.SimulationseedEditFieldLabel.Text = 'Simulation seed';
      app.SimulationseedEditField = uieditfield(app.RuntimeTab, 'numeric');
      app.SimulationseedEditField.Limits = [0 Inf];
      app.SimulationseedEditField.RoundFractionalValues = 'on';
      app.SimulationseedEditField.Value = 126;
      app.NetworkTab = uitab(app.TabGroup2);
      app.NetworkTab.Title = 'Network';
      app.RunTab = uitab(app.TabGroup);
      app.RunTab.Title = 'Run';
      app.Label_2 = uilabel(app.RunTab);
      app.Label_2.Text = 'Use this tab to run a simulation, first load the selected setup and then hit the run button.';
      app.RunasimulationLabel = uilabel(app.RunTab);
      app.RunasimulationLabel.Text = 'Run a simulation';
      app.Label_3 = uilabel(app.RunTab);
      app.Label_3.Text = 'While the simulation is running, log message will be displayed.';
      app.LoadsetupButton_2 = uibutton(app.RunTab, 'push');
      app.LoadsetupButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadsetupButton_2Pushed, true);
      app.LoadsetupButton_2.Text = 'Load setup';
      app.LoadthesetuptorunButton = uibutton(app.RunTab, 'push');
      app.LoadthesetuptorunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthesetuptorunButtonPushed, true);
      app.LoadthesetuptorunButton.Enable = 'off';
      app.LoadthesetuptorunButton.Text = 'Load the setup to run ';
      app.SimulationexecutionlogTextAreaLabel = uilabel(app.RunTab);
      app.SimulationexecutionlogTextAreaLabel.Text = 'Simulation execution log';
      app.SimulationexecutionlogTextArea = uitextarea(app.RunTab);
      app.SimulationexecutionlogTextArea.Editable = 'off';
      app.SimulationexecutionlogTextArea.Value = {'Simulation not started...'};
      app.ResultsTab = uitab(app.TabGroup);
      app.ResultsTab.Title = 'Results';
      % Show the figure after all components are created
      app.MONSTeRUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = monster

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.MONSTeRUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.MONSTeRUIFigure)
    end
  end
end