classdef monster_demo < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    TabGroup                      matlab.ui.container.TabGroup
    HomeTab                       matlab.ui.container.Tab
    TabGroup2                     matlab.ui.container.TabGroup
    RuntimeTab                    matlab.ui.container.Tab
    Label                         matlab.ui.control.Label
    RuntimesetupLabel             matlab.ui.control.Label
    Simulationrounds1roundis1msEditFieldLabel  matlab.ui.control.Label
    Simulationrounds1roundis1msEditField  matlab.ui.control.NumericEditField
    SimulationseedEditFieldLabel  matlab.ui.control.Label
    SimulationseedEditField       matlab.ui.control.NumericEditField
    Label_2                       matlab.ui.control.Label
    RunasimulationLabel           matlab.ui.control.Label
    RestoredefaultsetupButton     matlab.ui.control.Button
    LoadsetupButton_2             matlab.ui.control.Button
    LoadthesetuptorunButton       matlab.ui.control.Button
    Label_3                       matlab.ui.control.Label
    NetworkTab                    matlab.ui.container.Tab
    RunTab                        matlab.ui.container.Tab
    ResultsTab                    matlab.ui.container.Tab
    Image                         matlab.ui.control.Image
    MONSTeRLabel                  matlab.ui.control.Label
    SetupTab                      matlab.ui.container.Tab
  end

 
 properties (Access = private)
  simulationRounds % number of simulation rounds
  seed
  Config
  Logger
  Simulation
 end
 

  % Callbacks that handle component events
  methods (Access = private)

    % Value changed function: Simulationrounds1roundis1msEditField
    function Simulationrounds1roundis1msEditFieldValueChanged(app, event)
       value = app.Simulationrounds1roundis1msEditField.Value;
       app.simulationRounds = value;
    end

    % Button pushed function: LoadsetupButton_2
    function LoadsetupButton_2Pushed(app, event)
       set(app.LoadsetupButton_2, 'Text', 'Loading setup...')
       set(app.LoadsetupButton_2, 'Enable', 'off')
       app.Config = MonsterConfig();
       app.Config.Runtime.simulationRounds = app.simulationRounds;
       app.Config.Runtime.mode = 'app';
       % Pause 2 secs
       pause(2);
       set(app.LoadsetupButton_2, 'Text', 'Setup loaded')
       set(app.LoadthesetuptorunButton, 'Enable', 'on')
       set(app.LoadthesetuptorunButton, 'Text', 'Run')
    end

    % Button pushed function: LoadthesetuptorunButton
    function LoadthesetuptorunButtonPushed(app, event)
       set(app.LoadthesetuptorunButton, 'Enable', 'off');
       set(app.LoadthesetuptorunButton, 'Text', 'Simulation running');
       app.LoadthesetuptorunButton
       app.Logger = MonsterLog(app.Config);
       app.Simulation = Monster(app.Config, app.Logger);
       
       for iRound = 0:(app.Simulation.Runtime.totalRounds - 1)
       	app.Simulation.setupRound(iRound);
        
       	app.Simulation.Logger.log(sprintf('(MAIN) simulation round %i, time elapsed %f s, time left %f s',...
       		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.currentTime, ...
       		app.Simulation.Runtime.remainingTime ), 'NFO');
       	
       	app.Simulation.run();
        
       	app.Simulation.Logger.log(sprintf('(MAIN) completed simulation round %i. %i rounds left' ,....
       		app.Simulation.Runtime.currentRound, app.Simulation.Runtime.remainingRounds), 'NFO');
        
       	app.Simulation.collectResults();
        
       	app.Simulation.Logger.log('(MAIN) collected simulation round results', 'NFO');
        
       	app.Simulation.clean();
        
       	if iRound ~= app.Simulation.Runtime.totalRounds - 1
       	    app.Simulation.Logger.log('(MAIN) cleaned parameters for next round', 'NFO');
       	else
       	    app.Simulation.Logger.log('(MAIN) simulation completed', 'NFO');
       	end
       end
       set(app.LoadsetupButton_2, 'Enable', 'on')
       set(app.LoadsetupButton_2, 'Text', 'Load setup')
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'UI Figure';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.HomeTab = uitab(app.TabGroup);
      app.HomeTab.Title = 'Home';
      app.Image = uiimage(app.HomeTab);
      app.Image.ImageSource = 'monster.png';
      app.MONSTeRLabel = uilabel(app.HomeTab);
      app.MONSTeRLabel.Text = 'MONSTeR';
      app.SetupTab = uitab(app.TabGroup);
      app.SetupTab.Title = 'Setup';
      app.TabGroup2 = uitabgroup(app.SetupTab);
      app.RuntimeTab = uitab(app.TabGroup2);
      app.RuntimeTab.Title = 'Runtime';
      app.Label = uilabel(app.RuntimeTab);
      app.Label.Text = 'Use settings in this tab to edit the configuration for the simulation runtime, like length, etc.';
      app.RuntimesetupLabel = uilabel(app.RuntimeTab);
      app.RuntimesetupLabel.Text = 'Runtime setup';
      app.Simulationrounds1roundis1msEditFieldLabel = uilabel(app.RuntimeTab);
      app.Simulationrounds1roundis1msEditFieldLabel.Text = 'Simulation rounds (1 round is 1ms)';
      app.Simulationrounds1roundis1msEditField = uieditfield(app.RuntimeTab, 'numeric');
      app.Simulationrounds1roundis1msEditField.Limits = [1 Inf];
      app.Simulationrounds1roundis1msEditField.RoundFractionalValues = 'on';
      app.Simulationrounds1roundis1msEditField.ValueChangedFcn = createCallbackFcn(app, @Simulationrounds1roundis1msEditFieldValueChanged, true);
      app.Simulationrounds1roundis1msEditField.Value = 10;
      app.SimulationseedEditFieldLabel = uilabel(app.RuntimeTab);
      app.SimulationseedEditFieldLabel.Text = 'Simulation seed';
      app.SimulationseedEditField = uieditfield(app.RuntimeTab, 'numeric');
      app.SimulationseedEditField.Limits = [0 Inf];
      app.SimulationseedEditField.RoundFractionalValues = 'on';
      app.SimulationseedEditField.Value = 126;
      app.NetworkTab = uitab(app.TabGroup2);
      app.NetworkTab.Title = 'Network';
      app.RunTab = uitab(app.TabGroup);
      app.RunTab.Title = 'Run';
      app.Label_2 = uilabel(app.RunTab);
      app.Label_2.Text = 'Use this tab to run a simulation, first load the selected setup and then hit the run button.';
      app.RunasimulationLabel = uilabel(app.RunTab);
      app.RunasimulationLabel.Text = 'Run a simulation';
      app.Label_3 = uilabel(app.RunTab);
      app.Label_3.Text = 'While the simulation is running, log message will be displayed.';
      app.RestoredefaultsetupButton = uibutton(app.RunTab, 'push');
      app.RestoredefaultsetupButton.Text = 'Restore default setup';
      app.LoadsetupButton_2 = uibutton(app.RunTab, 'push');
      app.LoadsetupButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadsetupButton_2Pushed, true);
      app.LoadsetupButton_2.Text = 'Load setup';
      app.LoadthesetuptorunButton = uibutton(app.RunTab, 'push');
      app.LoadthesetuptorunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthesetuptorunButtonPushed, true);
      app.LoadthesetuptorunButton.Enable = 'off';
      app.LoadthesetuptorunButton.Text = 'Load the setup to run ';
      app.ResultsTab = uitab(app.TabGroup);
      app.ResultsTab.Title = 'Results';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = monster_demo

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end